classdef imscribe < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Panel_3                  matlab.ui.container.Panel
        TargetPrescripTextArea   matlab.ui.control.TextArea
        SourcePrescripTextArea   matlab.ui.control.TextArea
        window1                  matlab.ui.control.UIAxes
        window3                  matlab.ui.control.UIAxes
        window2                  matlab.ui.control.UIAxes
        window4                  matlab.ui.control.UIAxes
        Panel_2                  matlab.ui.container.Panel
        TargetStructTextArea     matlab.ui.control.TextArea
        SourceFOVSVSTextArea     matlab.ui.control.TextArea
        SourceStructTextArea     matlab.ui.control.TextArea
        SourceFOVSVSButton       matlab.ui.control.Button
        TargetStructButton       matlab.ui.control.Button
        SourceStructButton       matlab.ui.control.Button
        uipanel5                 matlab.ui.container.Panel
        ScratchButton            matlab.ui.control.Button
        RegistrationButtonGroup  matlab.ui.container.ButtonGroup
        InterModalsamesubjectdiffcontrastButton_2  matlab.ui.control.RadioButton
        AffinediffsubjectsamecontrastButton_2  matlab.ui.control.RadioButton
        RigidBodysamesubjectsamecontrastButton_2  matlab.ui.control.RadioButton
        ViewButtonGroup          matlab.ui.container.ButtonGroup
        ResetViewsButton         matlab.ui.control.Button
        CoronalButton            matlab.ui.control.RadioButton
        SagittalButton           matlab.ui.control.RadioButton
        TranverseButton          matlab.ui.control.RadioButton
        uibuttongroup1           matlab.ui.container.ButtonGroup
        StandardButton           matlab.ui.control.RadioButton
        SvsMaskButton            matlab.ui.control.RadioButton
        DrawSourceOnlyButton     matlab.ui.control.RadioButton
        ReuseXformCheckBox       matlab.ui.control.CheckBox
        GoButton                 matlab.ui.control.Button
        Panel                    matlab.ui.container.Panel
        text16                   matlab.ui.control.Label
        text14                   matlab.ui.control.Label
        text12                   matlab.ui.control.Label
        text15                   matlab.ui.control.Label
        sliderBRIGHTNESS         matlab.ui.control.Slider
        text13                   matlab.ui.control.Label
        sliderCONTOUR            matlab.ui.control.Slider
        text11                   matlab.ui.control.Label
        sliderSLICENUM           matlab.ui.control.Slider
    end

    
    methods (Access = private)
        % ---------------------------------------------------------------------
        % --- Select NIFTI or Dicoms for processsing
        % ---------------------------------------------------------------------
        function [path,files,names,nfiles,filetype,ext] = imscribe_choose_files(app,pathname)
            nfiles = []; filetype = []; ext = [];
            if ~ispc()  % seems to be some propblems with me_uipickfiles() on Windows??
                [files,path,~,ext,names] = me_uipickfiles(mfilename(),pathname,'','Prompt','Select one NIFTI or ALL desired Dicom files','REFilter','\.dcm$|\.nii$|\.IMA$|\.MR$|\.nii.gz$|\.rda$');
            else
                [files,path,~,ext,names] = uigetfile_plus(mfilename(),pathname,'','*.*','Select one NIFTI or ALL desired Dicom files','MultiSelect','On');
            end
            if isempty(files), return; end
            if ~iscell(files), files = {files}; end
            nfiles = numel(files);
            if nfiles > 1
                files = sort(files);
                path  = path{1};
                ext   = ext{1};
            else
                if iscell(ext), ext = ext{1}; end  
                if iscell(path), path = path{1}; end  
                if iscell(names), names = names{1}; end  
            end

            % --- Figure out if Dicom or NIFTI or .rda ---
            switch(lower(ext))
                case {'.dcm','.ima','mr'}
                    filetype = 'DICOM';
                case {'.nii'}
                    if (nfiles > 1), path = ''; fprintf(2,'ERROR - Only one NIFTI file should be selected\n'); return; end
                    filetype = 'NIFTI';
                case {'.gz'}
                    if (nfiles > 1), path = ''; fprintf(2,'ERROR - Only one NIFTI file should be selected\n'); return; end
                    fprintf(1,'Unzipping %s...\n',files);
                    files = gunzip(files);
                    filetype = 'NIFTI';
                case {'.rda'}
                    filetype = 'RDA';
                otherwise
                    path = ''; fprintf(2,'ERROR - Unrecognized file name extension "%s"\n',ext); return;
            end
            bring_app_tofront(app);
        end


        % ---------------------------------------------------------------------
        % --- Display data folder and filename in GUI
        % ---------------------------------------------------------------------
        function load_filetext(app,folder,names,appname)
            if (iscell(names)), names = [names{1} ' ... ' names{end}]; end
            maxlen = 80;
            len    = length(folder);
            if (len > maxlen), folder = ['...' folder((len-maxlen):end)]; end % limit string to fit in text box
            app.(appname).Value = {folder,names};
        end

        % ---------------------------------------------------------------------
        % --- Update the saved data and xform matrix
        % ---------------------------------------------------------------------
        function update_saved_results(app,data,mat)
            app.GoButton.UserData           = data;
            app.ReuseXformCheckBox.UserData = mat;
            app.ReuseXformCheckBox.Enable   = ~isempty(mat);
            app.ReuseXformCheckBox.Value    = 0;
        end

        % ---------------------------------------------------------------------
        % --- Get current options for imscribe_engine() call
        % ---------------------------------------------------------------------
        function opt = get_imscribe_opts(app)            
            optapp = get_app_options(app);   % GUI options
            opt    = app.UIFigure.UserData;  % get saved version (has permanent seetings like 'scratchpath')
            
            opt.svsmask        = optapp.SvsMaskButton;
            opt.drawsourceonly = optapp.DrawSourceOnlyButton;
            opt.contourlevel   = optapp.sliderCONTOUR;
            opt.slicenum       = optapp.sliderSLICENUM;
            opt.imbright       = optapp.sliderBRIGHTNESS;
            switch app.ViewButtonGroup.SelectedObject.Text
                case 'Transverse'
                    opt.displane = 1;
                case 'Coronal'
                    opt.displane = 2;
                case 'Sagittal'
                    opt.displane = 3;
            end
            switch app.RegistrationButtonGroup.SelectedObject.Text{1}
                case 'RigidBody'
                    opt.coregmethod = 'rigidbody';
                case 'Affine'
                    opt.coregmethod = 'affine';
                case 'Inter-Modal'
                    opt.coregmethod = 'intermodal';
            end
        end

        % ---------------------------------------------------------------------
        % --- Initialize GUI and SETTINGS
        % ---------------------------------------------------------------------
        function imscribe_init(app)              
            SPMver = get_spm_version('SPM8');  % MELLIOTT
            if (isempty(SPMver)), return; end

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app); 
            handles.output = hObject;
            guidata(hObject, handles);

            % initialize GUI objects 
            app.SourcePrescripTextArea.Value = '';
            app.TargetPrescripTextArea.Value = '';
            app.SourceStructTextArea.Value = '';
            app.SourceFOVSVSTextArea.Value = '';
            app.TargetStructTextArea.Value = '';
            axis(handles.window1,'off');
            axis(handles.window2,'off');
            axis(handles.window3,'off');
            axis(handles.window4,'off');

            % initialize Imscribe options
            opt.winid       = [handles.window1 handles.window2 handles.window3 handles.window4];
            opt.txtid       = [app.SourcePrescripTextArea app.TargetPrescripTextArea];
            if (ispref(mfilename(),'scratchpath'))
                opt.scratchpath = getpref(mfilename(),'scratchpath');
                if (~isfolder(opt.scratchpath))
                    fprintf(2,'WARNING: Saved "scratchpath" %s does not exist! Using %s instead\n',opt.scratchpath,pwd());
                    opt.scratchpath = [pwd() filesep()];
                end
            else
                fprintf(2,'WARNING: No default "scratchpath" has been set. Use the "Scratch Folder" button to choose one\n');
                opt.scratchpath = [pwd() filesep()];
            end
            fprintf(1,'Using "scratchpath" = %s\n',opt.scratchpath);
            app.UIFigure.UserData = opt; % save to GUI

            % zero stored data file info
            app.SourceStructTextArea.UserData = {[], []};
            app.SourceFOVSVSTextArea.UserData = {[], []};
            app.TargetStructTextArea.UserData = {[], []};     
            update_saved_results(app,{},[]);    % clear saved data and xform
        end
        
        % --------------------------------------------------------------------
        % --- get all GUI settings and return them in an options structure ---
        % --------------------------------------------------------------------
        function opt = get_app_options(app)
            appnames = properties(app); for i=1:numel(appnames), apptypes{i,1} = app.(appnames{i}).Type; end
            checkbox_list  = find(~cellfun(@isempty,strfind(appnames,'CheckBox')));
            menu_list      = find(~cellfun(@isempty,strfind(appnames,'Menu')));
            slider_list    = find(~cellfun(@isempty,strfind(appnames,'slider')));
            spinner_list   = find(~cellfun(@isempty,strfind(appnames,'Spinner'))       & cellfun(@isempty,strfind(appnames,'Label')));
            button_list    = find(~cellfun(@isempty,strfind(appnames,'Button'))        & ~cellfun(@isempty,strfind(apptypes,'uiradiobutton')));
            editfield_list = find(~cellfun(@isempty,strfind(appnames,'EditField'))     & cellfun(@isempty,strfind(appnames,'Label')));
            dropdown_list  = find(~cellfun(@isempty,strfind(appnames,'DropDown'))      & cellfun(@isempty,strfind(appnames,'Label')));
            text_list      = find(~cellfun(@isempty,strfind(appnames,'TextArea'))      & cellfun(@isempty,strfind(appnames,'Label')));
            % text_list      = find(contains(appnames,'TextArea'));
            for i=1:numel(checkbox_list),  opt.(appnames{checkbox_list(i)})  = app.(appnames{checkbox_list(i)}).Value; end
            for i=1:numel(menu_list),      opt.(appnames{menu_list(i)})      = app.(appnames{menu_list(i)}).Checked; end
            for i=1:numel(spinner_list),   opt.(appnames{spinner_list(i)})   = app.(appnames{spinner_list(i)}).Value; end
            for i=1:numel(button_list),    opt.(appnames{button_list(i)})    = app.(appnames{button_list(i)}).Value; end
            for i=1:numel(editfield_list), opt.(appnames{editfield_list(i)}) = app.(appnames{editfield_list(i)}).Value; end
            for i=1:numel(dropdown_list),  opt.(appnames{dropdown_list(i)})  = app.(appnames{dropdown_list(i)}).Value; end
            for i=1:numel(text_list),      opt.(appnames{text_list(i)})      = app.(appnames{text_list(i)}).Value; end
            for i=1:numel(slider_list),    opt.(appnames{slider_list(i)})    = app.(appnames{slider_list(i)}).Value; end
        end

        % ---------------------------------------------------------------------
        % --- assign GUI settings to corresponding options structure fields ---
        % ---------------------------------------------------------------------
        function set_app_options(app,opt,include_datafiles)
            if (nargin < 3), include_datafiles = 0; end
            appnames = fieldnames(opt);
            for i=1:numel(appnames)
                if (contains(appnames{i},'TextArea'))
                    if (include_datafiles),            app.(appnames{i}).Value   = opt.(appnames{i}); end
                elseif (contains(appnames{i},'Menu')), app.(appnames{i}).Checked = opt.(appnames{i});
                else,                                  app.(appnames{i}).Value   = opt.(appnames{i}); end
            end
        end

        % ---------------------------------------------------------------------
        % --- Redraw images (if data exists) ---
        % ---------------------------------------------------------------------
        function redraw_images(app)
            data = app.GoButton.UserData;
            if (isempty(data)), return; end
            opt = get_imscribe_opts(app);
            vols = data{1};
                I1vol = vols{1};
                I2vol = vols{2};
                I3vol = vols{3};
                I4vol = vols{4};
            cboxes = data{2};
                c1 = cboxes{1};
                c2 = cboxes{2};
                c3 = cboxes{3};
                c4 = cboxes{4};
                c2x = cboxes{5};
            imscribe_draw(I1vol,I3vol,I1vol,c1,c3,c1,c2,opt,[2 1 1]);
            if (~isempty(I4vol))
                imscribe_draw(I1vol,I4vol,I4vol,c1,c4,c4,c2x,opt,[4 3 2]);
            end
        end

        % --------------------------------------------------------------------
        % --- Hanndle bug that GUI gets pushed to back ---
        % --------------------------------------------------------------------
        function bring_app_tofront(app)
            figure(app.UIFigure);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function imscribe_OpeningFcn(app, varargin)
            % Ensure that the app appears on screen when run
            movegui(app.UIFigure, 'onscreen');
                       
            % Restore last position on screen
            app.UIFigure.Name = upper(mfilename());
            if (ispref(mfilename(),'position')), movegui(app.UIFigure, getpref(mfilename(),'position')); end
            
            % Restore last settings
            if (ispref(mfilename(),'default_options'))
                fprintf(1,'Loading previous settings opt...\n');
                optapp = getpref(mfilename(),'default_options');
                set_app_options(app,optapp);
            end

            % initialize imscribe environment
            imscribe_init(app); 
        end

        % Button pushed function: GoButton
        function GoButton_Callback(app, event)
            % make sure paths are specified
            p1 = app.SourceStructTextArea.UserData; files1 = p1{1}; filetype1 = p1{2};
            p2 = app.SourceFOVSVSTextArea.UserData; files2 = p2{1}; filetype2 = p2{2};
            p3 = app.TargetStructTextArea.UserData; files3 = p3{1}; filetype3 = p3{2};        
            optapp = get_app_options(app);   % GUI options
            if (optapp.SvsMaskButton)
                if (isempty(files1) || isempty(files2))
                    uialert(app.UIFigure,'For FOV/SVS MASK mode, you must specify both SOURCE images','User Error','Icon','error'); 
                    return;
                end
            elseif (optapp.DrawSourceOnlyButton)
                if (isempty(files1) || isempty(files2))
                    uialert(app.UIFigure,'For DRAW SOURCE ONLY mode, you must specify both SOURCE images','User Error','Icon','error'); 
                    return;
                end
            else
                if (isempty(files1) || isempty(files2) || isempty(files3))
                    uialert(app.UIFigure,'for STANDARD mode, you must specify the SOURCE and TARGET IMAGES','User Error','Icon','error'); 
                    return;
                end
            end
            opt           = get_imscribe_opts(app);
            opt.datatypes = {filetype1, filetype2, filetype3};
            if (~optapp.ReuseXformCheckBox)
                opt.useXform = [];
            else
                opt.useXform = app.ReuseXformCheckBox.UserData;
                if (isempty(opt.useXform))
                    choice = uiconfirm(app.UIFigure,'"Re-use Xform" option is selected but no previous Xform exists','Warning', 'Options',{'Continue','Abort'}, 'DefaultOption',2,'Icon','warning');
                    if (isequal(choice,'Abort')), return; end
                end
            end
            % clear plot windows
            app.SourcePrescripTextArea.Value = '';
            app.TargetPrescripTextArea.Value = '';
            for i=1:4, cla(opt.winid(i)); title(opt.winid(i),''); axis(opt.winid(i),'off'); end
            drawnow()
            
            % clear previous saved results
            update_saved_results(app,{},[]);    

            % run imscribe_engine()
            [vols,cboxes,mat] = imscribe_engine(files1,files2,files3,opt);
            if (~isempty(vols))
                update_saved_results(app,{vols,cboxes},mat);    
            end
        end

        % Button pushed function: TargetStructButton
        function TargetStructButton_Callback(app, event)
            [path3,path3files,names,nfiles,filetype] = imscribe_choose_files(app,'targetstruct');
            if (isempty(path3)), return; end
            if (filetype == 3), fprintf(2,'ERROR - RDA files should only be selected for the SOURCE FOV/ROI\n'); path3 = ''; return; end
            load_filetext(app,path3,names,'TargetStructTextArea');
            app.TargetStructTextArea.UserData = {path3files, filetype};
            
            % can't reuse Xform or old data if new struct files loaded
            update_saved_results(app,{},[]);    
        end

        % Button pushed function: SourceFOVSVSButton
        function SourceFOVSVSButton_Callback(app, event)
            [path2,path2files,names,nfiles,filetype] = imscribe_choose_files(app,'sourcefov');
            if (isempty(path2)), return; end
            load_filetext(app,path2,names,'SourceFOVSVSTextArea');
            app.SourceFOVSVSTextArea.UserData = {path2files, filetype};
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            guipos = app.UIFigure.Position;
            setpref(mfilename(),'position',guipos(1:2)); % save position of GUI for next time
            fprintf(1,'Saving current settings...\n');
            optapp = get_app_options(app);
            setpref(mfilename(),'default_options',optapp); % save the options structure in pref memory
            delete(app)        
        end

        % Button pushed function: SourceStructButton
        function SourceStructButtonCallback(app, event)
            [path1,path1files,names,nfiles,filetype] = imscribe_choose_files(app,'sourcestruct');
            if (isempty(path1)), return; end
            if (filetype == 3), fprintf(2,'ERROR - RDA files should only be selected for the SOURCE FOV/ROI\n'); path1 = ''; return; end
            load_filetext(app,path1,names,'SourceStructTextArea');
            app.SourceStructTextArea.UserData = {path1files, filetype};
                        
            % can't reuse Xform or old data if new struct files loaded
            update_saved_results(app,{},[]);    
        end

        % Selection changed function: ViewButtonGroup
        function ViewButtonGroupSelectionChanged(app, event)
            redraw_images(app);
        end

        % Value changed function: sliderBRIGHTNESS
        function sliderBRIGHTNESSValueChanged(app, event)
           if (isequal(event.EventName,'ValueChanging')), return; end  % callback gets called twice!
           redraw_images(app);            
        end

        % Value changed function: sliderCONTOUR
        function sliderCONTOURValueChanged(app, event)
            if (isequal(event.EventName,'ValueChanging')), return; end  % callback gets called twice!
            redraw_images(app);
        end

        % Value changed function: sliderSLICENUM
        function sliderSLICENUMValueChanged(app, event)
           if (isequal(event.EventName,'ValueChanging')), return; end  % callback gets called twice!
           redraw_images(app);            
        end

        % Selection changed function: RegistrationButtonGroup
        function RegistrationButtonGroupSelectionChanged(app, event)
            % can't reuse Xform if diff registration method
            update_saved_results(app,{},[]);    
        end

        % Button pushed function: ScratchButton
        function ScratchButtonPushed(app, event)
            scratchpath = uigetdir_plus(mfilename(),'scratchpath',pwd(),'Select a folder to use as the "scratch" path');
            if (~isempty(scratchpath))
                scratchpath = [scratchpath filesep()];
                fprintf(1,'Setting "scratchpath" = %s\n',scratchpath);
                setpref(mfilename(),'scratchpath',scratchpath);
                opt = app.UIFigure.UserData;  
                opt.scratchpath = scratchpath;
                app.UIFigure.UserData = opt;
            end
        end

        % Button pushed function: ResetViewsButton
        function ResetViewsButtonPushed(app, event)
            opt = get_imscribe_opts(app);
            switch opt.displane
                case 1      % Axial slice
                    angles1=[180,90]; angles2=[180,-90];
                case 2      % Coronal slice
                    angles1=[180,0]; angles2=[180,-90];
                case 3      % Sagittal slice
                    angles1=[89.9,0.1]; angles2=[0,90];
            end
            for i=1:4, xlim(opt.winid(i),'auto'); ylim(opt.winid(i),'auto'); zlim(opt.winid(i),'auto'); end
            view(opt.winid(1),angles1); view(opt.winid(3),angles1); 
            view(opt.winid(2),angles2); view(opt.winid(4),angles2); 
            h1 = rotate3d(opt.winid(1));  set(h1,'Enable','on');
            h1 = rotate3d(opt.winid(3));  set(h1,'Enable','on');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'imscribe';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.Tag = 'figure1';
            app.Panel = uipanel(app.UIFigure);
            app.sliderSLICENUM = uislider(app.Panel);
            app.sliderSLICENUM.MajorTicks = [];
            app.sliderSLICENUM.ValueChangedFcn = createCallbackFcn(app, @sliderSLICENUMValueChanged, true);
            app.sliderSLICENUM.MinorTicks = [];
            app.sliderSLICENUM.Tag = 'sliderSLICENUM';
            app.sliderSLICENUM.Value = 50;
            app.text11 = uilabel(app.Panel);
            app.text11.Tag = 'text11';
            app.text11.Text = 'Slice -';
            app.sliderCONTOUR = uislider(app.Panel);
            app.sliderCONTOUR.MajorTicks = [];
            app.sliderCONTOUR.ValueChangedFcn = createCallbackFcn(app, @sliderCONTOURValueChanged, true);
            app.sliderCONTOUR.MinorTicks = [];
            app.sliderCONTOUR.Tag = 'sliderCONTOUR';
            app.sliderCONTOUR.Value = 50;
            app.text13 = uilabel(app.Panel);
            app.text13.Tag = 'text13';
            app.text13.Text = 'Contour -';
            app.sliderBRIGHTNESS = uislider(app.Panel);
            app.sliderBRIGHTNESS.Limits = [0.01 2];
            app.sliderBRIGHTNESS.MajorTicks = [];
            app.sliderBRIGHTNESS.ValueChangedFcn = createCallbackFcn(app, @sliderBRIGHTNESSValueChanged, true);
            app.sliderBRIGHTNESS.MinorTicks = [];
            app.sliderBRIGHTNESS.Tag = 'sliderBRIGHTNESS';
            app.sliderBRIGHTNESS.Value = 1;
            app.text15 = uilabel(app.Panel);
            app.text15.Tag = 'text15';
            app.text15.Text = 'Darker';
            app.text12 = uilabel(app.Panel);
            app.text12.Tag = 'text12';
            app.text12.Text = 'Slice +';
            app.text14 = uilabel(app.Panel);
            app.text14.Tag = 'text14';
            app.text14.Text = 'Contour +';
            app.text16 = uilabel(app.Panel);
            app.text16.Tag = 'text16';
            app.text16.Text = 'Brighter';
            app.uipanel5 = uipanel(app.UIFigure);
            app.uipanel5.Tag = 'uipanel5';
            app.GoButton = uibutton(app.uipanel5, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButton_Callback, true);
            app.GoButton.Tag = 'buttonGO';
            app.GoButton.Text = 'GO';
            app.ReuseXformCheckBox = uicheckbox(app.uipanel5);
            app.ReuseXformCheckBox.Tag = 'checkbox_REUSE_xform';
            app.ReuseXformCheckBox.Text = 'Re-use Xform';
            app.uibuttongroup1 = uibuttongroup(app.uipanel5);
            app.uibuttongroup1.Title = 'Mode';
            app.uibuttongroup1.Tag = 'uibuttongroup1';
            app.DrawSourceOnlyButton = uiradiobutton(app.uibuttongroup1);
            app.DrawSourceOnlyButton.Tag = 'buttonTEMPLATES_ONLY';
            app.DrawSourceOnlyButton.Text = {'Draw Source'; 'only'};
            app.SvsMaskButton = uiradiobutton(app.uibuttongroup1);
            app.SvsMaskButton.Tag = 'buttonSVSMASK';
            app.SvsMaskButton.Text = {'FOV/SVS'; 'Mask'};
            app.StandardButton = uiradiobutton(app.uibuttongroup1);
            app.StandardButton.Tag = 'radiobuttonSTANDARD';
            app.StandardButton.Text = 'Standard';
            app.StandardButton.Value = true;
            app.ViewButtonGroup = uibuttongroup(app.uipanel5);
            app.ViewButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ViewButtonGroupSelectionChanged, true);
            app.ViewButtonGroup.Title = 'View';
            app.ViewButtonGroup.Tag = 'uipanel7';
            app.TranverseButton = uiradiobutton(app.ViewButtonGroup);
            app.TranverseButton.Tag = 'radioTRANSVERSE';
            app.TranverseButton.Text = 'Transverse';
            app.SagittalButton = uiradiobutton(app.ViewButtonGroup);
            app.SagittalButton.Tag = 'radioSAGITTAL';
            app.SagittalButton.Text = 'Sagittal';
            app.SagittalButton.Value = true;
            app.CoronalButton = uiradiobutton(app.ViewButtonGroup);
            app.CoronalButton.Tag = 'radioCORONAL';
            app.CoronalButton.Text = 'Coronal';
            app.ResetViewsButton = uibutton(app.ViewButtonGroup, 'push');
            app.ResetViewsButton.ButtonPushedFcn = createCallbackFcn(app, @ResetViewsButtonPushed, true);
            app.ResetViewsButton.Tag = 'buttonGO';
            app.ResetViewsButton.Text = 'Reset Views';
            app.RegistrationButtonGroup = uibuttongroup(app.uipanel5);
            app.RegistrationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @RegistrationButtonGroupSelectionChanged, true);
            app.RegistrationButtonGroup.Title = 'Registration';
            app.RigidBodysamesubjectsamecontrastButton_2 = uiradiobutton(app.RegistrationButtonGroup);
            app.RigidBodysamesubjectsamecontrastButton_2.Text = {'RigidBody'; '/same subject'; '/same contrast'};
            app.RigidBodysamesubjectsamecontrastButton_2.Value = true;
            app.AffinediffsubjectsamecontrastButton_2 = uiradiobutton(app.RegistrationButtonGroup);
            app.AffinediffsubjectsamecontrastButton_2.Text = {'Affine'; '/diff subject'; '/same contrast'};
            app.InterModalsamesubjectdiffcontrastButton_2 = uiradiobutton(app.RegistrationButtonGroup);
            app.InterModalsamesubjectdiffcontrastButton_2.Text = {'Inter-Modal'; '/same subject'; '/diff contrast'};
            app.ScratchButton = uibutton(app.uipanel5, 'push');
            app.ScratchButton.ButtonPushedFcn = createCallbackFcn(app, @ScratchButtonPushed, true);
            app.ScratchButton.Tag = 'buttonGO';
            app.ScratchButton.Text = 'Scratch Folder';
            app.Panel_2 = uipanel(app.UIFigure);
            app.SourceStructButton = uibutton(app.Panel_2, 'push');
            app.SourceStructButton.ButtonPushedFcn = createCallbackFcn(app, @SourceStructButtonCallback, true);
            app.SourceStructButton.Tag = 'buttonTEMPLATE';
            app.SourceStructButton.Text = 'Source Structural';
            app.TargetStructButton = uibutton(app.Panel_2, 'push');
            app.TargetStructButton.ButtonPushedFcn = createCallbackFcn(app, @TargetStructButton_Callback, true);
            app.TargetStructButton.Tag = 'buttonTARGET';
            app.TargetStructButton.Text = 'Target Structural';
            app.SourceFOVSVSButton = uibutton(app.Panel_2, 'push');
            app.SourceFOVSVSButton.ButtonPushedFcn = createCallbackFcn(app, @SourceFOVSVSButton_Callback, true);
            app.SourceFOVSVSButton.Tag = 'buttonTEMPLATE_ROI';
            app.SourceFOVSVSButton.Text = 'Source FOV/SVS';
            app.SourceStructTextArea = uitextarea(app.Panel_2);
            app.SourceStructTextArea.Editable = 'off';
            app.SourceStructTextArea.Value = {'Source Struct'; 'folder'};
            app.SourceFOVSVSTextArea = uitextarea(app.Panel_2);
            app.SourceFOVSVSTextArea.Editable = 'off';
            app.SourceFOVSVSTextArea.Value = {'Source FOV'; 'folder'};
            app.TargetStructTextArea = uitextarea(app.Panel_2);
            app.TargetStructTextArea.Editable = 'off';
            app.TargetStructTextArea.Value = {'Target Struct'; 'folder'};
            app.Panel_3 = uipanel(app.UIFigure);
            app.window4 = uiaxes(app.Panel_3);
            app.window4.XTick = [];
            app.window4.YTick = [];
            app.window4.Tag = 'window4';
            app.window2 = uiaxes(app.Panel_3);
            app.window2.XTick = [];
            app.window2.YTick = [];
            app.window2.Tag = 'window2';
            app.window3 = uiaxes(app.Panel_3);
            app.window3.XTick = [];
            app.window3.YTick = [];
            app.window3.Tag = 'window3';
            app.window1 = uiaxes(app.Panel_3);
            app.window1.XTick = [];
            app.window1.YTick = [];
            app.window1.Tag = 'window1';
            app.SourcePrescripTextArea = uitextarea(app.Panel_3);
            app.SourcePrescripTextArea.Value = {'Source Prescription'};
            app.TargetPrescripTextArea = uitextarea(app.Panel_3);
            app.TargetPrescripTextArea.Value = {'Target Prescription'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = imscribe(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)imscribe_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end